classdef OWI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OWIUIFigure                     matlab.ui.Figure
        ArduinoPortMenu                 matlab.ui.container.Menu
        Grid_Main                       matlab.ui.container.GridLayout
        UpdatePlotButton                matlab.ui.control.Button
        LightButton                     matlab.ui.control.StateButton
        UIAxes                          matlab.ui.control.UIAxes
        DockPanel                       matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        TextArea                        matlab.ui.control.TextArea
        UndockButton                    matlab.ui.control.StateButton
        Stop                            matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        DisplayTab                      matlab.ui.container.Tab
        Grid_Display                    matlab.ui.container.GridLayout
        SlideSettings                   matlab.ui.container.Panel
        Grid_SlideSettings              matlab.ui.container.GridLayout
        MotorDropDown                   matlab.ui.control.DropDown
        ResetcurrentslideButton         matlab.ui.control.Button
        ResetallslidesButton            matlab.ui.control.Button
        MinEditField_2Label             matlab.ui.control.Label
        SlideMin                        matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        SlideMax                        matlab.ui.control.NumericEditField
        LimitButton                     matlab.ui.control.Button
        AxisSettings                    matlab.ui.container.Panel
        Grid_AxisSettings               matlab.ui.container.GridLayout
        Minx                            matlab.ui.control.NumericEditField
        Maxx                            matlab.ui.control.NumericEditField
        Miny                            matlab.ui.control.NumericEditField
        Minz                            matlab.ui.control.NumericEditField
        Maxy                            matlab.ui.control.NumericEditField
        Maxz                            matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        yLabel                          matlab.ui.control.Label
        zLabel                          matlab.ui.control.Label
        AxisSet                         matlab.ui.control.Button
        AxisReset                       matlab.ui.control.Button
        MinLabel                        matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        DHTable                         matlab.ui.control.Table
        RemoteSettings                  matlab.ui.container.Panel
        Grid_Remote                     matlab.ui.container.GridLayout
        Remote5                         matlab.ui.control.StateButton
        Remote4                         matlab.ui.control.StateButton
        Remote3                         matlab.ui.control.StateButton
        Remote2                         matlab.ui.control.StateButton
        Remote1                         matlab.ui.control.StateButton
        ViewSettings                    matlab.ui.container.Panel
        Grid_ViewSettings               matlab.ui.container.GridLayout
        ViewYX                          matlab.ui.control.Button
        ViewXZ                          matlab.ui.control.Button
        ViewYZ                          matlab.ui.control.Button
        ViewReset                       matlab.ui.control.Button
        ViewRotateButton                matlab.ui.control.StateButton
        ViewIso                         matlab.ui.control.Button
        View_YZ                         matlab.ui.control.Button
        View_XZ                         matlab.ui.control.Button
        PowerPanel                      matlab.ui.container.Panel
        Grid_Power                      matlab.ui.container.GridLayout
        Power1                          matlab.ui.control.Spinner
        Power2                          matlab.ui.control.Spinner
        Power3                          matlab.ui.control.Spinner
        Power4                          matlab.ui.control.Spinner
        Power5                          matlab.ui.control.Spinner
        UploadAllButton                 matlab.ui.control.Button
        ResetArmButton                  matlab.ui.control.Button
        RadiansButton                   matlab.ui.control.StateButton
        ControlPanel                    matlab.ui.container.Panel
        Grid_Control                    matlab.ui.container.GridLayout
        SlideReset1                     matlab.ui.control.Button
        SlideText1                      matlab.ui.control.NumericEditField
        Slide1                          matlab.ui.control.Slider
        Upload1                         matlab.ui.control.Button
        SlideReset2                     matlab.ui.control.Button
        SlideText2                      matlab.ui.control.NumericEditField
        Slide2                          matlab.ui.control.Slider
        Upload2                         matlab.ui.control.Button
        SlideReset3                     matlab.ui.control.Button
        SlideText3                      matlab.ui.control.NumericEditField
        Slide3                          matlab.ui.control.Slider
        Upload3                         matlab.ui.control.Button
        SlideReset4                     matlab.ui.control.Button
        SlideText4                      matlab.ui.control.NumericEditField
        Slide4                          matlab.ui.control.Slider
        Upload4                         matlab.ui.control.Button
        SlideReset5                     matlab.ui.control.Button
        SlideText5                      matlab.ui.control.NumericEditField
        Slide5                          matlab.ui.control.Slider
        Upload5                         matlab.ui.control.Button
        DisplaySettingsButtonGroup      matlab.ui.container.ButtonGroup
        AxisLimitsButton                matlab.ui.control.ToggleButton
        SlideLimitsButton               matlab.ui.control.ToggleButton
        DHButton                        matlab.ui.control.ToggleButton
        ArduinoSetupTab                 matlab.ui.container.Tab
        Grid_Arduino                    matlab.ui.container.GridLayout
        CalPanel                        matlab.ui.container.Panel
        Grid_Calibrate                  matlab.ui.container.GridLayout
        OrientButton                    matlab.ui.control.Button
        ReadPotButton                   matlab.ui.control.Button
        PotReadingEditFieldLabel        matlab.ui.control.Label
        PotReadingEditField             matlab.ui.control.NumericEditField
        CalLoadButton                   matlab.ui.control.Button
        CalSaveButton                   matlab.ui.control.Button
        SetCalButton                    matlab.ui.control.Button
        CalGroup                        matlab.ui.container.ButtonGroup
        M1CalButton                     matlab.ui.control.ToggleButton
        M2CalButton                     matlab.ui.control.ToggleButton
        M3CalButton                     matlab.ui.control.ToggleButton
        M4CalButton                     matlab.ui.control.ToggleButton
        M5CalButton                     matlab.ui.control.ToggleButton
        PinSettingsPanel                matlab.ui.container.Panel
        Grid_Pins                       matlab.ui.container.GridLayout
        InterruptLabel                  matlab.ui.control.Label
        Interrupt                       matlab.ui.control.NumericEditField
        PinSetButton                    matlab.ui.control.Button
        PinSaveButton                   matlab.ui.control.Button
        PinLoadButton                   matlab.ui.control.Button
        DirLabel                        matlab.ui.control.Label
        EnLabel                         matlab.ui.control.Label
        LightLabel                      matlab.ui.control.Label
        Light                           matlab.ui.control.NumericEditField
        M5PotPinEditFieldLabel          matlab.ui.control.Label
        APin1                           matlab.ui.control.NumericEditField
        M5OutputPinsEditFieldLabel      matlab.ui.control.Label
        En1                             matlab.ui.control.NumericEditField
        M4PotPinLabel                   matlab.ui.control.Label
        APin2                           matlab.ui.control.NumericEditField
        M4OutputPinsLabel               matlab.ui.control.Label
        En2                             matlab.ui.control.NumericEditField
        M3PotPinLabel                   matlab.ui.control.Label
        APin3                           matlab.ui.control.NumericEditField
        M3OutputPinsLabel               matlab.ui.control.Label
        En3                             matlab.ui.control.NumericEditField
        M2PotPinLabel                   matlab.ui.control.Label
        APin4                           matlab.ui.control.NumericEditField
        M2OutputPinsLabel               matlab.ui.control.Label
        En4                             matlab.ui.control.NumericEditField
        M1PotPinLabel                   matlab.ui.control.Label
        APin5                           matlab.ui.control.NumericEditField
        M1OutputPinsLabel               matlab.ui.control.Label
        En5                             matlab.ui.control.NumericEditField
        Dir1                            matlab.ui.control.NumericEditField
        Dir2                            matlab.ui.control.NumericEditField
        Dir3                            matlab.ui.control.NumericEditField
        Dir4                            matlab.ui.control.NumericEditField
        Dir5                            matlab.ui.control.NumericEditField
        AutomationTab                   matlab.ui.container.Tab
        Grid_Automation                 matlab.ui.container.GridLayout
        ClosedLoopPanel                 matlab.ui.container.Panel
        Grid_Program                    matlab.ui.container.GridLayout
        ConfigurationsListBoxLabel      matlab.ui.control.Label
        ConfigListBox                   matlab.ui.control.ListBox
        UploadMotionButton              matlab.ui.control.Button
        DisplayMotionButton             matlab.ui.control.StateButton
        DeleteConfigButton              matlab.ui.control.Button
        SaveMotionButton                matlab.ui.control.Button
        LoadMotionButton                matlab.ui.control.Button
        ResetMotionButton               matlab.ui.control.Button
        SlowMotionButton                matlab.ui.control.StateButton
        SaveconfigafterselectedButton   matlab.ui.control.Button
        SaveconfigbeforeselectedButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Arduino
        Default %ArmLimits() %Pins()
        DH %Data() %Input()
        Menu
        Transform %Body() %Grip()
        UI %APin %Dir %En %Slide() %SlideText() %SlideReset() %Power() %Remote() %Upload()
        Pins %Ana() %En() %Dir() %Pot %Pot.Max().Value %Pot.Max().Set %Pot.Min().Value %Pot.Min().Set %Light %Interrupt
        ArmtoReset
        Flag %ArduinoInit %Interrupt %MotorDropDownInit %PinSet %ResetSlide
        Patch %Body %Gripper
        KeyBuffer
        ControlsDock % Description
        
        Program
        Tolerance
        SaveIndex
        SelectedMotor % Description
        MotorIndex % Description
        Tester % Description
    end
    
    methods (Access = private)


        function Config(app,input,varargin)
            app.Flag.Interrupt = false;
            %             stop = 0;
            if nargin == 2
                %                 for i = 1:numel(input)
                %                     app.UI.Slide(i).Value = input(i).*pi/180;
                %                     app.UI.SlideText(i).Value = app.UI.Slide(i).Value*app.RadCheck(i);
                %                 end
                input = cellfun(@(x) x*pi/180,input,'un',0);
                input{5} = input{5}*180/pi;
                input = cell2struct(input,'Value',1);
                [app.UI.Slide.Value] = deal(input.Value);
                value = cell2struct(num2cell([app.UI.Slide.Value].*app.RadCheck(1:5)),'Value',1);
                [app.UI.SlideText.Value] = deal(value.Value);
                app.UpdatePlot
            else % Fancy Display for Preview window
                SlideConfig = app.GetPlotConfig;
                SlideConfig = [SlideConfig{:}];
                inputvalue = [input{:}];
                change = inputvalue-SlideConfig;
                change_intvalue =  change - rem( change,1);
                signed_decrement = (change_intvalue/10);
%                 signed_decrement = (change_intvalue./abs( change_intvalue));
                inputvalue = SlideConfig*pi/180;
                inputvalue(5) = inputvalue(5)*180/pi;
                if any( change_intvalue)
%                     for k = 1:max(abs( change_intvalue))
                    for k = 1:10
                        index =  change_intvalue ~= 0;
                        inputvalue( index) =  SlideConfig( index)+k*signed_decrement( index);
                        inputvalue(index(1:4)) =  inputvalue(index(1:4))*pi/180;
                        structinput = cell2struct(num2cell(inputvalue),'Value',1);
                        [app.UI.Slide.Value] = deal(structinput.Value);
                        app.UpdatePlot();
                        pause(0.05);
                        change_intvalue( index) =  change_intvalue( index) -  signed_decrement( index);
                        if ~app.DisplayMotionButton.Value
                            app.Flag.Interrupt = true;
                            break
                        end
                    end
                    app.Config(input);
                else
                    app.Config(input);
                end
            end
        end
        
        function result = GetPlotConfig(app)
            %RETURNS THE VIRTUAL ARM'S CONFIG
            result = num2cell([app.UI.Slide.Value].*app.RadCheck(1:5));
        end
        
        function result = GetRobotConfig(app)
            %RETURNS THE PHYSICAL ARM'S CURRENT CONFIG TO THE PROGRAM
            radresult = app.Map(app.analogRead(app.Arduino,app.Pins.Ana'),[[app.Pins.Pot.Min.Value]' [app.Pins.Pot.Max.Value]'],app.Default.ArmLimits);
            result = num2cell(radresult.*app.RadCheck(1:5)');
        end
        
        function result = ImproperPinValues(app,APins,DPins)
            % CHECK FOR LEGAL PIN VALUES
            result = true;
            APin_s = strcat('A',string(APins));
            DPin_s = strcat('D',string(DPins));
            % make sure pins are not repeated
            if app.RepeatedEntries(APins)
                app.Status('Repeated analog input pin');
            elseif app.RepeatedEntries(DPins)
                app.Status('Repeated digital output pin');
                % make sure pins are within the right range
            elseif ~all(ismember(APin_s,app.Arduino.AvailablePins))
                app.Status('An analog input pin is out of range');
            elseif ~all(ismember(DPin_s,app.Arduino.AvailablePins))
                app.Status('A Digital output pin is out of range');
            else
                result = false;
            end
        end
                
        function InitializeArduino(app,src,~)
            %             Status(app.TextArea,'love');
            app.Arduino = arduino(src.Text);
            if isa(app.Arduino,'arduino')
                app.OWIUIFigure.Name = strcat('OWI_GUI (',app.Arduino.Port,')');
                src.Parent.Visible = 'off';
                app.Flag.ArduinoInit = true;
                app.Status('');
                app.Status('Arduino initialized');
            else
                app.Status('Failed to initialize arduino');
            end
        end
        
        function result = RadCheck(app,index)
            %CONVERTS FROM RAD TO DEGRESS IF RAD BUTTON IS PUSHED
            result = ones(size(index));
            if ~app.RadiansButton.Value
                result(~(index == 5)) = 180/pi;
            end
        end
        
        %Display update
        function Status(app,message,varargin)
            index = size(app.TextArea.Value,1);
            if nargin == 1
                app.TextArea.Value = '';
            elseif index && nargin < 3
                previous = app.TextArea.Value(index,:);
                if isnumeric(message)
                    message = num2str(message);
                end
                if index == 1 && strcmp(app.TextArea.Value,'')
                    app.TextArea.Value = message;
                elseif contains(previous,message)
                    if index == 1
                        app.TextArea.Value = strcat(app.TextArea.Value,'.');
                    else
                        app.TextArea.Value(index,:) = strcat(app.TextArea.Value(index,:),'.');
                    end
                elseif index < 10
                    app.TextArea.Value = [app.TextArea.Value;{message}];
                else
                    app.TextArea.Value(1,:) = [];
                    temp = app.TextArea.Value;
                    app.TextArea.Value = [temp;{message}];
                end
            else
                if isnumeric(message)
                    message = num2str(message);
                end
                app.TextArea.Value = message;
            end
        end
        
        function EmStop(app)
            %EMERGENCY STOP
            app.digitalWrite(app.Arduino,[app.Pins.En app.Pins.Dir app.Pins.Light], zeros(1,11));
        end
        
        function UpdatePlot(app,varargin)
            values=[app.UI.Slide(:).Value];
            if nargin > 1
                values(abs(varargin{1,1}(:,1))) = varargin{1,1}(:,2);
            end
            
            %for every arm but the gripper, update the transforms
            for i = 1:size(app.DH.Input,1)
                app.DH.Data(i,:) = [app.DH.Input(i,1), app.DH.Input(i,2), app.DH.Input(i,3), -values(i)];
                if i == 1
                    app.Transform.Body(i).Matrix  = app.Htrans(app.DH.Data(i,:));
                else
                    app.Transform.Body(i).Matrix  = app.Transform.Body(i-1).Matrix * app.Htrans(app.DH.Data(i,:));
                end
            end
            app.DH.Data(:,4) = -app.DH.Data(:,4).*app.RadCheck(1:4)';
            app.DHTable.Data = flipud(app.DH.Data);
            %gripper transforms
            %pi/90 constant converts a full scale value of 45mm to a pi/2 degree turn
            app.Transform.Grip(1).Matrix = app.Transform.Body(4).Matrix * app.Htrans([   0, pi/2,  12.1, 0]) * app.Htrans([0,0,0, values(5) * pi/90]);
            app.Transform.Grip(2).Matrix = app.Transform.Body(4).Matrix * app.Htrans([   0, pi/2, -12.1, 0]) * app.Htrans([0,0,0,-values(5) * pi/90]);
            app.Transform.Grip(3).Matrix = app.Transform.Body(4).Matrix * app.Htrans([16.8, pi/2,   6.3, 0]) * app.Htrans([0,0,0, values(5) * pi/90]);
            app.Transform.Grip(4).Matrix = app.Transform.Body(4).Matrix * app.Htrans([16.8, pi/2,  -6.3, 0]) * app.Htrans([0,0,0,-values(5) * pi/90]);
            app.Transform.Grip(5).Matrix = app.Transform.Grip(3).Matrix * app.Htrans([  23,    0,     0, 0]) * app.Htrans([0,0,0,-values(5) * pi/90]);
            app.Transform.Grip(6).Matrix = app.Transform.Grip(4).Matrix * app.Htrans([  23,    0,     0, 0]) * app.Htrans([0,0,0, values(5) * pi/90]);
        end
        
        
        %GET DIRECTION TOWARDS GOAL
        function [Direction,Dist] = getDir(app,val,goal)
            Direction = ones(size(val));
            if isnumeric(goal)
                range = [app.Pins.Pot.Max(val).Value] - [app.Pins.Pot.Min(val).Value];
                scale = range./abs(range); %([Pins.Pot.Max(val).Value] - [Pins.Pot.Min(val).Value])./abs([Pins.Pot.Max(val).Value] - [Pins.Pot.Min(val).Value]);
                goalVoltage = map(goal',app.Default.ArmLimits(val,:),[[app.Pins.Pot.Min(val).Value]' [app.Pins.Pot.Max(val).Value]'].*scale');
                goalOffset = (goalVoltage - app.analogRead(app.Arduino,(app.Pins.Ana(val))').*scale');
                Dist = abs(goalOffset);
                Direction(abs(goalOffset) < tolerance) = NaN;
                Direction(abs(goalOffset) > tolerance & goalOffset < 0) = 0;
                Direction(abs(goalOffset) > tolerance & goalOffset > 0) = 1;
            else
                Dist = Direction;
                Direction(strcmp(goal,'-')) = 0;
                Direction(strcmp(goal,'+')) = 1;
                Direction(~(strcmp(goal,'+')|strcmp(goal,'-'))) = NaN;
            end
        end
        
        %MOVE ARM
        function move(app,val,goal)
            Dir = app.getDir(val,goal); % Get the direction of the movement (0) for negative, (1) for positive, and NaN for idle joints
            IdlePins = isnan(Dir); % Get index of Idle joints
            app.digitalWrite(app.Arduino,app.Pins.Dir(val(~IdlePins)),Dir(~IdlePins)); %Update the Direction pins of non-idle joints
            app.Flag.Interrupt = false; % reset interrupt
            if app.analogRead(app.Arduino,app.Pins.Interrupt)> 4 %Check for the shunt and only move with the shunt removed.
                if ~(strcmp(goal,'+')||strcmp(goal,'-')) && sum(~IdlePins) %perform this section is source is not the virtual remote
                    %analogWrite(Arduino,Pins.En(val(~IdlePins)),Data.PowerSlide(Val(~IdlePins)).Value/100*2.5*ones(numel(find(~IdlePins)),1));
                    app.digitalWrite(app.Arduino,app.Pins.En(val(~IdlePins)),ones(numel(find(~IdlePins)),1)); %Start move by enabling non idle joints
                    n = numel(find(IdlePins));
                    while (n < numel(Dir) && ~app.Flag.Interrupt && app.analogRead(app.Arduino,app.Pins.Interrupt) > 4)
                        Dir = app.getDir(val,goal);
                        NewIdlePins = isnan(Dir);
                        n = numel(find(NewIdlePins));
                        NewIdlePins = xor(NewIdlePins,IdlePins);
                        pause(0.1);
                        if sum(NewIdlePins)
                            app.digitalWrite(app.Arduino,app.Pins.En(val(NewIdlePins)),zeros(numel(find(NewIdlePins)),1));
                        end
                        IdlePins = IdlePins|NewIdlePins;
                    end
                    app.EmStop;
                else
                    for i = 1:numel(val)
                        if strcmp(goal(i),'+')||strcmp(goal(i),'-')
                            app.analogWrite(app.Arduino,app.Pins.En(val(i)),app.UI.Power(val(i)).Value * 2.5);
                        else
                            app.EmStop;
                        end
                    end
                end
            else
                app.Status('Remove Jumper or release RESET');
            end
        end
        
        function CloseDock(app,src,~)
            app.UndockButton.Value = 0;
            src.Visible = false;
            app.UndockButtonPushed;
        end
    end
    
    methods (Access = public)
        
        function result = analogRead(~,Arduino,pin)
            %ANALOG READ
            result = zeros(size(pin));
            pin = strcat('A',num2str(pin));
            for i = 1:size(pin,1)
                result(i) = Arduino.readVoltage(pin(i,:));
            end
        end
        
        function analogWrite(~,Arduino,pin,Value)
            %ANALOG WRITE
            for i = 1:numel(pin)
                Arduino.writePWMVoltage(strcat('D',num2str(pin(i))),Value(i));
            end
        end
        
        function result = CheckCal(app)
            % CHECK IF ALL PINS HAVE BEEN CALIBRATED
            if  (all([app.Pins.Pot.Min(1:5).Set]) && all([app.Pins.Pot.Max(1:5).Set]))
                result = true;
            else
                result = false;
            end
        end
        
        function digitalWrite(~,Arduino,pin,Value)
            %DIGITAL WRITE
            for i = 1:numel(pin)
                Arduino.writeDigitalPin(strcat('D',num2str(pin(i))),Value(i));
            end
        end
        
        function result = Htrans(~,DH)%DH = (r,alpha,d,theta)
            %MAKE TRANSFORMATION MATRIX
            R_z_theta   = makehgtform('zrotate',DH(4));
            T_z_d       = makehgtform('translate',0,0,DH(3));
            T_x_r       = makehgtform('translate',DH(1),0,0);
            R_x_alpha   = makehgtform('xrotate',DH(2));
            result = R_z_theta * T_z_d * T_x_r * R_x_alpha;
        end
        
        function results = InRange(~,value,Range)
            more = Range >value;
            less = Range <value;
            results = xor(more(1),more(2))|xor(less(1),less(2));
        end
        
        function result = Map(~,val,range1,range2)
            %MAP FUNCTION (converts a val in range1 to a result in range2)
            x1 = range1(:,1);
            y1 = range1(:,2);
            x2 = range2(:,1);
            y2 = range2(:,2);
            result = (val - x1).*(y2 - x2)./(y1-x1)+ x2;
        end
        
        function result = RepeatedEntries(~,Pins)
            %CHECK IF ANY DIGITAL OR ANALONG PINS ARE REPEATED
            result = false;
            for i = 1:numel(Pins)-1
                checkval = Pins(i);
                for k = i:numel(Pins)-1
                    if checkval == Pins(k+1)
                        result = true;
                        break;
                    end
                end
                if result
                    break;
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Arduino)
            %{To Do
            % Comment
            % Insert Motion
            %}
            app.KeyBuffer = {'a' 'a' 'a'};
            app.Tolerance = 0.2; %volts
            app.Flag.Interrupt = false;
            app.SaveIndex = 1;
            clc
            
            %             app.Pins = struct;      %storage for pin tab children
            %             app.Settings = struct;	%storage for settings
            %             app.Program = struct;	%storage for prgram tab children
            %             File = struct;      %temp data storage for saving and loading
            
            %arm DH parameters
            app.DH.Data = zeros(4);
            app.DH.Input = [...
                0, -pi/2, 0, Inf;
                90,     0, 0, Inf;
                118,     0, 0, Inf;
                41,     0, 0, Inf];
            
            %Parameter limits
            app.Default.ArmLimits  = [...
                -3*pi/4, 3*pi/4; % M5
                0,     pi; % M4
                -3*pi/4, 3*pi/4; % M3
                -pi/3,   pi/3; % M2
                0,    45]; % Gripper
            
            app.Flag.PinSet = false; %Pin set flag
            app.Program = [];
            
            comPorts = seriallist;
            for i= 1:numel(comPorts)
                app.Menu(i)=uimenu(app.ArduinoPortMenu, 'Text', comPorts(i),...
                    'MenuSelectedFcn', @app.Initialize_Arduino);
            end
            
            for count = 1:4
                app.Transform.Body(count) = hgtransform(app.UIAxes);
            end
            for count = 1:5
                app.Pins.Ana(count) = NaN;
                app.Pins.En(count) = NaN;
                app.Pins.Dir(count) = NaN;
                app.Pins.Pot.Min(count).Value = NaN;
                app.Pins.Pot.Min(count).Set = false;
                app.Pins.Pot.Max(count).Value = NaN;
                app.Pins.Pot.Max(count).Set = false;
                app.UI.Slide(count) = eval(strcat('app.Slide',num2str(count)));
                app.UI.Slide(count).Tag = string(count);
                app.UI.SlideText(count) = eval(strcat('app.SlideText',num2str(count)));
                app.UI.SlideText(count).Tag = string(count);
                app.UI.SlideReset(count) = eval(strcat('app.SlideReset',num2str(count)));
                app.UI.SlideReset(count).Tag = string(count);
                app.UI.Upload(count) = eval(strcat('app.Upload',num2str(count)));
                app.UI.Upload(count).Tag = string(count);
                app.UI.Power(count) = eval(strcat('app.Power',num2str(count)));
                app.UI.Power(count).Tag = string(count);
                app.UI.APin(count) = eval(strcat('app.APin',num2str(count)));
                app.UI.APin(count).Tag = string(count);
                app.UI.En(count) = eval(strcat('app.En',num2str(count)));
                app.UI.En(count).Tag = string(count);
                app.UI.Dir(count) = eval(strcat('app.Dir',num2str(count)));
                app.UI.Dir(count).Tag = string(count);
                app.UI.Remote(count) = eval(strcat('app.Remote',num2str(count)));
                app.UI.Remote(count).Tag = string(count);
            end
            app.M1CalButton.Tag = '5';
            app.M2CalButton.Tag = '4';
            app.M3CalButton.Tag = '3';
            app.M4CalButton.Tag = '2';
            app.M5CalButton.Tag = '1';
            app.ResetArmButton.Tag = '6';
            app.UploadAllButton.Tag = '12345';
            
            for count = 1:6
                app.Transform.Grip(count) = hgtransform(app.UIAxes);
            end
            
            axis(app.UIAxes,'image');
            view(app.UIAxes,-150,30);
            rotate3d(app.UIAxes,'on');
            defaultColor = [231 188 34]./255;
            %Base1
            [Vertices, Faces , ~, ~]= stlReadBinary('Models/Base_1.stl');
            app.Patch.Body(1) = patch(app.UIAxes,'Faces',Faces,'Vertices',Vertices,...
                'FaceColor',defaultColor,'Parent',app.Transform.Body(1),'EdgeColor',defaultColor/2);%
            %Base2
            [Vertices, Faces , ~, ~]= stlReadBinary('Models/Base_2.stl');
            app.Patch.Body(2) = patch(app.UIAxes,'Faces',Faces,'Vertices',Vertices,...
                'FaceColor',[.1 .1 .1],'Parent',app.Transform.Body(1));
            % Arm1
            [Vertices, Faces , ~, ~]= stlReadBinary('Models/arm1.stl');
            app.Patch.Body(3) = patch(app.UIAxes,'Faces',Faces,'Vertices',Vertices,...
                'Parent',app.Transform.Body(2),'FaceColor',[.1 .1 .1]);
            %Arm2_1
            [Vertices, Faces, ~, ~]= stlReadBinary('Models/arm2_1.stl');
            app.Patch.Body(4) = patch(app.UIAxes,'Faces',Faces,'Vertices',Vertices,...
                'FaceColor',[.1 .1 .1],'Parent',app.Transform.Body(3));
            %Arm2_2
            [Vertices, Faces, ~, ~]= stlReadBinary('Models/arm2_2.stl');
            app.Patch.Body(5) = patch(app.UIAxes,'Faces',Faces,'Vertices',Vertices,...
                'FaceColor',defaultColor,'Parent',app.Transform.Body(3),'EdgeColor',defaultColor/2);%
            %Gripper
            [Vertices, Faces, ~, ~]= stlReadBinary('Models/gripper.stl');
            app.Patch.Gripper(1) = patch(app.UIAxes,'Faces',Faces,'Vertices',Vertices,...
                'Parent',app.Transform.Body(4),'FaceColor',[.1 .1 .1]);
            %Gripper_1
            [Vertices, Faces, ~, ~]= stlReadBinary('Models/gripper_1.stl');
            app.Patch.Gripper(2) = patch(app.UIAxes,'Faces',Faces,'Vertices',Vertices,...
                'Parent',app.Transform.Grip(1),'FaceColor',[.19 .19 .19]);
            %Gripper_2
            [Vertices, Faces, ~, ~]= stlReadBinary('Models/gripper_2.stl');
            app.Patch.Gripper(3) = patch(app.UIAxes,'Faces',Faces,'Vertices',Vertices,...
                'Parent',app.Transform.Grip(2),'FaceColor',[.19 .19 .19]);
            %Gripper_3 and 4
            [Vertices, Faces, ~, ~]= stlReadBinary('Models/gripper_3.stl');
            app.Patch.Gripper(4) = patch(app.UIAxes,'Faces',Faces,'Vertices',Vertices,...
                'Parent',app.Transform.Grip(3),'FaceColor',[.19 .19 .19]);
            [Vertices, Faces, ~, ~]= stlReadBinary('Models/gripper_3.stl');
            app.Patch.Gripper(5) = patch(app.UIAxes,'Faces',Faces,'Vertices',Vertices,...
                'Parent',app.Transform.Grip(4),'FaceColor',[.19 .19 .19]);
            %Gripper_5 and 6
            [Vertices, Faces, ~, ~]= stlReadBinary('Models/gripper_4l.stl');
            app.Patch.Gripper(6) = patch(app.UIAxes,'Faces',Faces,'Vertices',Vertices,...
                'Parent',app.Transform.Grip(5),'FaceColor',[.19 .19 .19]);
            [Vertices, Faces, ~, ~]= stlReadBinary('Models/gripper_4r.stl');
            app.Patch.Gripper(7) = patch(app.UIAxes,'Faces',Faces,'Vertices',Vertices,...
                'Parent',app.Transform.Grip(6),'FaceColor',[.19 .19 .19]);
            %Battery
            [Vertices, Faces, ~, ~]= stlReadBinary('Models/battery.stl');
            app.Patch.Body(6) = patch(app.UIAxes,'Faces',Faces,'Vertices',Vertices,...
                'FaceColor',[.1 .1 .1]);
            %Table
            TableTrans = hgtransform('Parent',app.UIAxes,'Matrix',makehgtform('translate',[0 0 -69]));
            %             rectangle(app.UIAxes,'Position',[-175 -175 350 350],'Parent',TableTrans,...
            %                 'Curvature',[1,1],'FaceColor',[.8 .8 .8]);
            rectangle(app.UIAxes,'Position',[-165 -50 220 100],'Parent',TableTrans,...
                'FaceColor',[.6 .7 .8]);
            
            app.ResetAxisCallback;
            
            app.Flag.MotorDropDownInit = true;
            MotorDropDownValueChanged(app);
            
            app.Flag.ResetSlide = true;
            ResetSlideLimitsCallback(app);
            
            app.Flag.ArduinoInit = false;
            app.SelectedMotor = 0;
            app.UpdatePlot;
            
            if nargin>1
                app.Arduino = Arduino;
                app.OWIUIFigure.Name = strcat('OWI_GUI (',app.Arduino.Port,')');
                app.ArduinoPortMenu.Visible = false;
                app.Flag.ArduinoInit = true;
                app.Status('');
                app.Status('Arduino initialized');
            end
            %             app.ControlsDock = uifigure('Visible','off','Name','Controls',...
            %                 'CloseRequestFcn',@app.CloseDock, 'Position',[200 50 300 500]);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            %TabChanged_Callback
            selectedTab = app.TabGroup.SelectedTab;
            app.Status();
            switch selectedTab.Title
                case 'Arduino Setup'
                    %                     app.DisplaySettingsButtonGroup.Visible = false;
                    %                     app.SlowMotionButton.Visible = false;
                    app.RemoteSettings.Parent = app.Grid_Arduino;
                    app.RemoteSettings.Layout.Row = 1;
                    app.RemoteSettings.Layout.Column = 1;
                case 'Display'
                    %                     app.DisplaySettingsButtonGroup.Visible = true;
                    %                     app.SlowMotionButton.Visible = false;
                    app.RemoteSettings.Parent = app.Grid_Display;
                    app.RemoteSettings.Layout.Row = [2 6];
                    app.RemoteSettings.Layout.Column = 3;
                    app.ControlPanel.Parent = app.Grid_Display;
                    app.ControlPanel.Layout.Row = 1;
                    app.ControlPanel.Layout.Column = [1 2];
                case 'Automation'
                    %                     app.DisplaySettingsButtonGroup.Visible = false;
                    %                     app.SlowMotionButton.Visible = true;
                    app.RemoteSettings.Parent = app.Grid_Automation;
                    app.RemoteSettings.Layout.Row = 2;
                    app.RemoteSettings.Layout.Column = 1;
                    app.ControlPanel.Parent = app.Grid_Automation;
                    app.ControlPanel.Layout.Row = 1;
                    app.ControlPanel.Layout.Column = [1 2];
            end
        end

        % Menu selected function: ArduinoPortMenu
        function ArduinoPortMenuSelected(app, event)
            %Menu_Callback
            delete(app.ArduinoPortMenu.Children);
            items = seriallist; %eg ["Com3" "com4" "com9"];
            for i= 1:numel(items)
                app.Menu(i) = uimenu(app.ArduinoPortMenu, 'Text', items(i),...
                    'MenuSelectedFcn', @app.InitializeArduino);
            end
        end

        % Selection changed function: DisplaySettingsButtonGroup
        function DisplaySettingsButtonGroupSelectionChanged(app, event)
            %AdvancedControls_Callback
            selectedButton = app.DisplaySettingsButtonGroup.SelectedObject;
            switch selectedButton.Text{1}
                case 'Axis'
                    app.AxisSettings.Visible = true;
                    app.SlideSettings.Visible = false;
                    app.DHTable.Visible = false;
                case 'Slide'
                    app.AxisSettings.Visible = false;
                    app.SlideSettings.Visible = true;
                    app.DHTable.Visible = false;
                case 'DH'
                    app.AxisSettings.Visible = false;
                    app.SlideSettings.Visible = false;
                    app.DHTable.Visible = true;
            end
        end

        % Button pushed function: AxisReset, AxisSet
        function ResetAxisCallback(app, event)
            % AxisLimit_Callback
            % ResetAxis_Callback
            if exist("event",'var') && strcmp(event.Source.Text,'Set')
                axis(app.UIAxes,[app.Minx.Value app.Maxx.Value app.Miny.Value app.Maxy.Value app.Minz.Value app.Maxz.Value]);
            else
                %                 axis(app.UIAxes,[-350 350 -350 350  -75 625]);
                %         axis(app.UIAxes,[200 350 -75 75  -75 75]);
                %                 axis(app.UIAxes,[-175  320 -175  175  -69   25]);
                axis(app.UIAxes,'image');
                app.Minx.Value = -350;
                app.Maxx.Value = 350;
                app.Miny.Value = -350;
                app.Maxy.Value = 350;
                app.Minz.Value = -75;
                app.Maxz.Value = 625;
            end
        end

        % Button pushed function: ResetallslidesButton, 
        % ResetcurrentslideButton
        function ResetSlideLimitsCallback(app, event)
            %ResetLimits_Callback
            if ~app.Flag.ResetSlide && strcmp(event.Source.Text,'Reset current slide')
                val = eval(app.MotorDropDown.Value);
                app.UI.Slide(val).Limits = [app.Default.ArmLimits(val,1) app.Default.ArmLimits(val,2)];
                app.SlideMin.Value = app.UI.Slide(val).Limits(1).*app.RadCheck(val);
                app.SlideMax.Value = app.UI.Slide(val).Limits(2).*app.RadCheck(val);
                app.UI.Slide(val).Value = 0;
                app.UI.SlideText(val).Value = 0;
            else
                app.Flag.ResetSlide = false;
                for i = 1:5
                    app.UI.Slide(i).Limits = [app.Default.ArmLimits(i,1) app.Default.ArmLimits(i,2)];
                end
                val = eval(app.MotorDropDown.Value);
                app.SlideMin.Value = app.UI.Slide(val).Limits(1).*app.RadCheck(val);
                app.SlideMax.Value = app.UI.Slide(val).Limits(2).*app.RadCheck(val);
                app.ArmtoReset = 6;
                app.ResetArmCallback;
            end
        end

        % Button pushed function: ResetArmButton, SlideReset1, 
        % SlideReset2, SlideReset3, SlideReset4, SlideReset5
        function ResetArmCallback(app, event)
            %ResetArms_Callback
            val = app.ArmtoReset;
            if exist("event",'var')
                %                 index = app.Indexer(event.Source);
                index = eval(event.Source.Tag);
                if index<1 || index>5
                    val = 6;
                else
                    val = index;
                end
            end
            if val < 6
                if app.InRange(0,app.UI.Slide(val).Limits)
                    app.UI.Slide(val).Value = 0;
                    app.UI.SlideText(val).Value = 0;
                else
                    app.UI.Slide(val).Value = app.UI.Slide(val).Limits(1);
                    app.UI.SlideText(val).Value = app.UI.Slide(val).Limits(1).*app.RadCheck(val);
                end
                app.UpdatePlot;
            else
                for i=1:5
                    app.ArmtoReset = i;
                    app.ResetArmCallback;
                end
                app.ArmtoReset = 6;
            end
        end

        % Value changed function: MotorDropDown
        function MotorDropDownValueChanged(app, event)
            if app.Flag.MotorDropDownInit
                val = 5;
                app.Flag.MotorDropDownInit = false;
            else
                val = eval(app.MotorDropDown.Value);
            end
            app.SlideMin.Value = app.UI.Slide(val).Limits(1).*app.RadCheck(val);
            app.SlideMax.Value = app.UI.Slide(val).Limits(2).*app.RadCheck(val);
        end

        % Button pushed function: LimitButton
        function LimitSlideCallback(app, event)
            %SlideLimit_Callback
            val = eval(app.MotorDropDown.Value);
            if abs(app.SlideMin.Value-app.SlideMax.Value)
                if app.SlideMin.Value < app.SlideMax.Value
                    app.UI.Slide(val).Limits = [app.SlideMin.Value app.SlideMax.Value]./app.RadCheck(val);
                    app.UI.Slide(val).Value = app.SlideMin.Value./app.RadCheck(val); %RadCheck
                    app.UI.SlideText(val).Value = app.UI.Slide(val).Value.*app.RadCheck(val); %RadCheck
                    app.UpdatePlot;
                else
                    app.Status('invalid limits');
                    app.SlideMin.Value = app.UI.Slide(val).Limits(1);
                    app.SlideMax.Value = app.UI.Slide(val).Limits(2);
                end
            end
        end

        % Value changed function: Slide1, Slide2, Slide3, Slide4, 
        % Slide5
        function SliderChangedCallback(app, event)
            %Slide_Callback
            index = eval(event.Source.Tag);
            app.UI.SlideText(index).Value = app.UI.Slide(index).Value*app.RadCheck(index);
            app.UpdatePlot
        end

        % Value changed function: RadiansButton
        function DegreeChangeCallback(app, event)
            %Radian_Callback
            value = app.RadiansButton.Value;
            for i = 1:4
                app.UI.SlideText(i).Value = app.UI.Slide(i).Value.*app.RadCheck(i);
                if value
                    app.UI.SlideText(i).ValueDisplayFormat='%.4f';
                    app.UI.SlideText(i).FontColor = [1,1,1];
                    app.UI.SlideText(i).BackgroundColor = [0,0,0];
                else
                    app.UI.SlideText(i).ValueDisplayFormat='%.1f';
                    app.UI.SlideText(i).FontColor = [0,0,0];
                    app.UI.SlideText(i).BackgroundColor = [1,1,1];
                end
            end
            val = eval(app.MotorDropDown.Value);
            app.SlideMin.Value = app.UI.Slide(val).Limits(1).*app.RadCheck(val);
            app.SlideMax.Value = app.UI.Slide(val).Limits(2).*app.RadCheck(val);
            if value
                app.RadiansButton.Text = 'Radians';
            else
                app.RadiansButton.Text = 'Degrees';
            end
            
        end

        % Value changed function: SlideText1, SlideText2, 
        % SlideText3, SlideText4, SlideText5
        function SlideTextValueChanged(app, event)
            %SlideText_Callback
            index = eval(event.Source.Tag);
            value = event.Source.Value./app.RadCheck(index);
            if app.InRange(value,app.UI.Slide(index).Limits)
                app.UI.Slide(index).Value = value;
            else
                app.Status('Value out of range');
                app.ArmtoReset = index;
                app.ResetArmCallback;
            end
            app.UpdatePlot;
        end

        % Callback function: ViewIso, ViewReset, ViewRotateButton, 
        % ViewXZ, ViewYX, ViewYZ, View_XZ, View_YZ
        function ViewCallback(app, event)
            %Display_Callback
            switch event.Source.Text
                case 'YX'
                    view(app.UIAxes,-90,90);
                case 'XZ'
                    view(app.UIAxes,0,0);
                case 'XZ'''
                    view(app.UIAxes,-180,0);
                case 'YZ'
                    view(app.UIAxes,90,0);
                case 'YZ'''
                    view(app.UIAxes,-90,0);
                case 'Reset'
                    view(app.UIAxes,-150,30);
                case 'Isometric'
                    [az,~]=view(app.UIAxes);
                    az = az/45 - mod(az,45)/45;
                    if -1 <= az && az < 1
                        view(app.UIAxes,-45,30);
                    elseif 1 <= az && az <3
                        view(app.UIAxes,45,30);
                    elseif 3 <= az && az <5 ||-5< az && az <-3
                        view(app.UIAxes,135,30);
                    else
                        view(app.UIAxes,225,30);
                    end
                case 'Rotate'
                    if event.Source.Value
                        rotate3d(app.UIAxes,'on')
                    else
                        rotate3d(app.UIAxes,'off')
                    end
            end
        end

        % Button pushed function: Stop
        function StopCallback(app, event)
            app.Flag.Interrupt = true;
            if app.Flag.PinSet
                app.EmStop;
            else
                app.Status('Pins haven''t been setup yet');
            end
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlotCallback(app, event)
            %PlotUpdate_Callback
            %called by Data.UpdatePlot in the Main Figure
            if  app.CheckCal
                app.Config(app.GetRobotConfig);
            elseif ~app.Flag.PinSet
                app.Status('Can''t Update yet. Please set Pins');
            else
                app.Status('Can''t Update yet. Please Calibrate');
            end
        end

        % Value changing function: Slide1, Slide2, Slide3, Slide4, 
        % Slide5
        function SliderChangingCallback(app, event)
            changingValue = event.Value;
            app.UpdatePlot([eval(event.Source.Tag) changingValue]);
        end

        % Selection changed function: CalGroup
        function CalButtonCallback(app, event)
            %CalButton_Callback
            selectedButton = app.CalGroup.SelectedObject;
            val = eval(selectedButton.Tag);
            if ~(app.Pins.Pot.Min(val).Set && app.Pins.Pot.Max(val).Set)
                app.Pins.Pot.Min(val).Set = false;
                app.Pins.Pot.Max(val).Set = false;
                app.Status(strcat('Click Orient to Start Calibrating M',num2str(6-val)));
                app.SetCalButton.Visible = false;
                app.OrientButton.Visible = true;
                app.PotReadingEditField.Value = app.analogRead(app.Arduino,app.Pins.Ana(val));
            else
                app.Status(strcat('M',num2str(6-val),' has been calibrated already. click Reset to recalibrate'));
                app.Status(', or select a different Motor to calibrate');
                app.SetCalButton.Visible = true;
                app.OrientButton.Visible = false;
                app.SetCalButton.Text = 'Reset';
                app.PotReadingEditField.Value = app.analogRead(app.Arduino,app.Pins.Ana(val));
            end
        end

        % Button pushed function: OrientButton
        function OrientationCallback(app, event)
            %Orientation_Callback
            val = str2double(app.CalGroup.SelectedObject.Tag);
            if app.Flag.PinSet
                switch val
                    case 1
                        if ~app.Pins.Pot.Min(val).Set
                            app.Config({-135 0 0 0 0})
                            app.Status('Move the robot to the configuration shown, then click Set');
                        else
                            app.Config({135 0 0 0 0});
                            app.Status('Now move the robot to the New configuration shown , then click Set');
                        end
                        view(app.UIAxes,-90,90)
                    case 2
                        if ~app.Pins.Pot.Min(val).Set
                            app.Config({0 0 90 0 0});
                            app.Status('For this calibration, try to get the arm vertical with the base of the housing for M3 touching the top of the battery pack, then click Set');
                        else
                            app.Config({0 180 -90 0 0});
                            app.Status('For this Calibration, move M4 down until the joint Clicks, then click Set');
                        end
                        view(app.UIAxes,180,0)
                    case 3
                        if ~app.Pins.Pot.Min(val).Set
                            app.Config({0 180 -135 0 0});
                            app.Status('For this calibration, turn M3 until it''s hitting the housing for M4, then click Set');
                        else
                            app.Config({0 0 135 0 0});
                            app.Status('For this Calibration, turn M3 until it''s hitting the housing for M4, then click Set');
                        end
                        view(app.UIAxes,180,0)
                    case 4
                        if ~app.Pins.Pot.Min(val).Set
                            app.Config({0 90 0 -60 0});
                            app.Status('For this calibration, turn M2 until it clicks');
                        else
                            app.Config({0 90 0 60 0});
                            app.Status('For this Calibration, turn M2 until it clicks');
                        end
                        view(app.UIAxes,180,0)
                    case 5
                        if ~app.Pins.Pot.Min(val).Set
                            app.Config({0 90 0 0 0});
                            app.Status('For this calibration, turn M1 until it clicks');
                        else
                            app.Config({0 90 0 0 45});
                            app.Status('For this Calibration, turn M1 until it clicks');
                        end
                        view(app.UIAxes,-90,0)
                end
                app.SetCalButton.Text = 'Set';
                app.SetCalButton.Visible = true;
                %                 app.PotReadingEditField.Editable = true;
            else
                app.Status('Please Setup Pins');
            end
        end

        % Button pushed function: SetCalButton
        function CalibrateCallback(app, event)
            %Calibrate_Callback
            %Called by Settings.Cal.Set on the CalSettings uipanel
            val = eval(app.CalGroup.SelectedObject.Tag);
            Motor = strcat('M',num2str(6-val));
            if ~app.Pins.Pot.Min(val).Set
                app.Pins.Pot.Min(val).Value = app.analogRead(app.Arduino,app.Pins.Ana(val));
                %                 app.Pins.Pot.Min(val).Value = app.PotReadingEditField.Value;
                app.Pins.Pot.Min(val).Set = true;
                app.PotReadingEditField.Value = app.Pins.Pot.Min(val).Value;
                app.Status(strcat(Motor,' Min has been Set, Click Orient to proceed'));
            elseif ~app.Pins.Pot.Max(val).Set
                app.Pins.Pot.Max(val).Value = app.analogRead(app.Arduino,app.Pins.Ana(val));
                %                 app.Pins.Pot.Max(val).Value = app.PotReadingEditField.Value;
                app.Pins.Pot.Max(val).Set = true;
                app.PotReadingEditField.Value = app.Pins.Pot.Max(val).Value;
                app.Status(strcat(Motor,' Max been calibrated. Click Reset to recalibrate'));
                app.Status(' or select a different Motor to calibrate');
                event.Source.Text = 'Reset';
                app.OrientButton.Visible = false;
            else
                app.Pins.Pot.Min(val).Set = false;
                app.Pins.Pot.Max(val).Set = false;
                event.Source.Text = 'Set';
                app.OrientButton.Visible = true;
                app.Status(strcat('Click Orient to begin calibration'));
            end
        end

        % Button pushed function: ReadPotButton
        function ReadPotCallback(app, event)
            val = eval(app.CalGroup.SelectedObject.Tag);
            app.PotReadingEditField.Value = app.analogRead(app.Arduino,app.Pins.Ana(val));
        end

        % Value changed function: LightButton, Remote1, Remote2, 
        % Remote3, Remote4, Remote5
        function RemoteCallback(app, event)
            %Remote_Callback
            options = ["M5" "M4" "M3" "M2" "M1" "Light"];
            if exist("event",'var')
                val = str2double(event.Source.Tag);
                text = event.Source.Text;
            else
                val = app.SelectedMotor;
                text = options(val);
            end
            for i = 1:5
                if i == val
                    continue
                end
                app.UI.Remote(i).Value = 0;
            end
            if app.Flag.PinSet
                [~,Index] = ismember(text,options);
                app.SelectedMotor = Index;
                switch Index
                    case 1
                        app.Status('Use the left and right arrow keys to move.');
                        app.Status('Push any other key to stop');
                    case 6
                        if app.LightButton.Value
                            app.digitalWrite(app.Arduino,app.Pins.Light,1);
                            for i = 1:7
                                app.Patch.Gripper(i).FaceColor = [.5 .4 .3];
                                app.Patch.Gripper(i).EdgeColor = [.85 .75 .65];
                            end
                        else
                            app.digitalWrite(app.Arduino,app.Pins.Light,0);
                            for i = 1:7
                                app.Patch.Gripper(i).FaceColor = [.19 .19 .19];
                                app.Patch.Gripper(i).EdgeColor = [0 0 0];
                            end
                        end
                    otherwise
                        app.Status('Use the up and down arrow keys to move.');
                        app.Status('Push any other key to stop');
                end
            else
                app.Status('Set Arduino pins first');
            end
        end

        % Button pushed function: PinSetButton
        function SetPinCallback(app, event)
            %SetPin_Callback
            app.Status('');
            AnaPins = [app.UI.APin(:).Value];% app.Interrupt.Value];
            EnPins = [app.UI.En(:).Value];% app.Light.Value];
            DirPins =[app.UI.Dir(:).Value];
            APins = [AnaPins app.Interrupt.Value];
            DPins = [EnPins app.Light.Value DirPins];
            if app.Flag.ArduinoInit
                if  app.ImproperPinValues(APins,DPins)
                    app.Status(sprintf('Recheck Values'));
                else
                    app.Status(sprintf('Values Check Out'));
                    app.Pins.Ana = AnaPins;
                    app.Pins.En = EnPins;
                    app.Pins.Dir = DirPins;
                    app.Pins.Light = app.Light.Value;
                    app.Pins.Interrupt = app.Interrupt.Value;
                    app.Flag.PinSet = true;
                    IRQ = strcat('A',num2str(app.Interrupt.Value));
                    configurePin(app.Arduino,IRQ,'Pullup');
                    app.CalGroup.SelectedObject = app.M1CalButton;
                    app.CalButtonCallback;
                    app.CalPanel.Visible = true;
                    app.StopCallback;
                    app.LightButton.Visible = true;
                    app.Remote1.Enable = true;
                    app.Remote2.Enable = true;
                    app.Remote3.Enable = true;
                    app.Remote4.Enable = true;
                    app.Remote5.Enable = true;
                    app.EmStop;
                end
            else
                app.Status('Arduino board not Specified');
            end
        end

        % Button pushed function: Upload1, Upload2, Upload3, 
        % Upload4, Upload5, UploadAllButton
        function UploadCallback(app, event)
            %upload_Callback
            if exist("event",'var')
                val = str2num(event.Source.Tag')';
            else
                val = app.MotorIndex;
            end
            goal = [app.UI.Slide(val).Value];
            if app.CheckCal
                app.move(val,goal);
            elseif ~app.Flag.PinSet
                app.Status('Setup pins first');
            else
                app.Status('Pins need to be calibrated');
            end
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            (eval(value{1}));
        end

        % Callback function
        function ConfigSetCallback(app, event)
            %ProgramSave_Callback
            app.Program(app.SaveIndex).Config = app.GetPlotConfig;
            app.Status([app.Program(app.SaveIndex).Config{:}]);
            app.Status(strcat('Config ',num2str(app.SaveIndex),' saved'));
            app.ConfigListBox.Items{app.SaveIndex} = strcat('Config ',num2str(app.SaveIndex));
            app.ConfigListBox.ItemsData{app.SaveIndex} = app.SaveIndex;
            app.SaveIndex = app.SaveIndex + 1;
        end

        % Button pushed function: ResetMotionButton
        function ConfigResetCallback(app, event)
            app.SaveIndex = 1;
            app.DisplayMotionButton.Value = 0;
            app.Program = [];
            app.ConfigListBox.Items = {};
            app.ConfigListBox.ItemsData = {};
            app.Config({0 0 0 0 0});
            app.Status('Motion cancelled');
        end

        % Button pushed function: DeleteConfigButton
        function ConfigDeleteCallback(app, event)
            if app.SaveIndex <= 2
                app.ConfigResetCallback
            else
                value = app.ConfigListBox.Value;
                app.SaveIndex = app.SaveIndex -1;
                app.Program(value)=[];
                app.ConfigListBox.Items(app.SaveIndex) = [];
                app.ConfigListBox.ItemsData(app.SaveIndex) = [];
                if value == app.SaveIndex
                    value = value-1;
                end
                app.Config(app.Program(value).Config);
                app.ConfigListBox.Value = value;
                app.Status(strcat(app.ConfigListBox.Items{value},' deleted'));
            end
        end

        % Value changed function: DisplayMotionButton
        function DisplayMotionCallback(app, event)
            %ProgramMove_Callback
            if numel(app.Program) && event.Source.Value
                for i = 1:app.SaveIndex-1
                    app.ConfigListBox.Value = i;
                    if app.DisplayMotionButton.Value
                        if app.SlowMotionButton.Value
                            app.Config(app.Program(i).Config,'slowly')
                        else
                            app.Config(app.Program(i).Config);
                            pause(.5);
                        end
                    else
                        break
                    end
                end
            elseif ~(numel(app.Program))
                app.Status('No saved motion');
            else
                app.Status('Motion canceled');
            end
            event.Source.Value = 0;
        end

        % Value changed function: ConfigListBox
        function ListConfigCallback(app, event)
            %ProgramList_Callback
            app.Config(app.Program(event.Source.Value).Config);
        end

        % Button pushed function: UploadMotionButton
        function UploadMotionCallback(app, event)
            %Called by Program.Display and Program.Upload in the RemoteTab
            if ~app.Flag.PinSet
                app.Status('Set pins');
            elseif ~app.CheckCal
                app.Status('Calibrate pins');
            elseif numel(app.Program)
                if  app.CheckCal
                    app.Status('Uploading');
                    for i = 1:saveindex-1
                        app.Config(app.Program(i).Config);
                        app.MotorIndex = (1:5);
                        app.UploadCallback;
                    end
                    app.Status('Done');
                end
            else
                app.Status('No saved motion');
            end
        end

        % Window key press function: OWIUIFigure
        function OWIUIFigureWindowKeyPress(app, event)
            key = event.Key;
            app.KeyBuffer(1) = [];
            app.KeyBuffer{3} = key;
            if all(ismember({'a' 'b' 'c'},app.KeyBuffer))
                app.TextArea.Editable = true;
            elseif all(ismember({'a' 'b' 'd'},app.KeyBuffer))
                app.KeyBuffer
                app.TextArea.Editable = false;
            end
            if app.Flag.PinSet
                options = ["5" "4" "3" "2" "1" "uparrow" "downarrow" "leftarrow" "rightarrow"];
                [~,Index] = ismember(key,options);
                if Index
                    if Index < 6
                        app.SelectedMotor = Index;
                        app.UI.Remote(Index).Value = 1;
                        app.RemoteCallback;
                    end
                    %                     app.EmStop;
                    Dir = {'+','-'};
                    if app.SelectedMotor == 1 && ~any(ismember(key,options(6:7)))
                        %Move
                        if Index == 8
                            app.move(app.SelectedMotor,'-')
                        elseif Index == 9
                            app.move(app.SelectedMotor,'+')
                        end
                        app.Remote2.Value = 0;
                        app.Remote3.Value = 0;
                        app.Remote4.Value = 0;
                        app.Remote5.Value = 0;
                    elseif app.SelectedMotor > 1 && ~any(ismember(key,options(8:9)))
                        if app.SelectedMotor == 5 || app.SelectedMotor == 3
                            Dir = fliplr(Dir);
                        end
                        if Index == 6
                            app.move(app.SelectedMotor,Dir{1});
                        elseif Index == 7
                            app.move(app.SelectedMotor,Dir{2});
                        end
                        app.Remote1.Value = 0;
                        for i = 2:5
                            if i == app.SelectedMotor
                                continue
                            end
                            app.UI.Remote(i).Value = 0;
                        end
                    else
                        if app.SelectedMotor
                            app.UI.Remote(app.SelectedMotor).Value = 0;
                        end
                        app.SelectedMotor = 0;
                        app.EmStop;
                    end
                else
                    app.SelectedMotor = 0;
                end
                %             else
                %                 app.Status('Pins not declared');
            end
        end

        % Button pushed function: LoadMotionButton
        function LoadMotionCallback(app, event)
            %             uiopen('Settings/Move/Movement.mat');
            File = [];
            uiopen('load');
            if isfield(File,'Config')
                app.Program = [app.Program, File];
                for i = 1:size(app.Program,2)
                    app.ConfigListBox.Items{i} = strcat('Config ',num2str(i));
                    app.ConfigListBox.ItemsData{i} = i;
                end
                app.SaveIndex = size(app.Program,2)+1;
                app.ConfigListBox.Value = app.SaveIndex-1;
            else
                app.Status('Invalid Motion file');
            end
            figure(app.OWIUIFigure);
        end

        % Button pushed function: PinLoadButton
        function LoadPinsCallback(app, event)
            %             uiopen('Settings/Pins/Pins.mat');
            File = [];
            uiopen('load');
            if numel(File) == 3
                for i = 1:5
                    app.UI.APin(i).Value = File{1}.Ana(i);
                    app.UI.En(i).Value = File{1}.En(i);
                    app.UI.Dir(i).Value = File{1}.Dir(i);
                end
                app.Light.Value = File{2};
                app.Interrupt.Value = File{3};
                app.Status('Pin info loaded');
            else
                app.Status('Invalid Pin setup file');
            end
            figure(app.OWIUIFigure);
        end

        % Button pushed function: CalLoadButton
        function LoadCalibrationCallback(app, event)
            %             uiopen('Settings/Cal/Calibration.mat');
            File = [];
            uiopen('load');
            if numel(File) == 1
                app.Pins.Pot(:) = File{1};
                app.Status('Calibration file loaded');
            else
                app.Status('Invalid Calibration file');
            end
            figure(app.OWIUIFigure);
        end

        % Button pushed function: CalSaveButton
        function SaveCalibrationCallback(app, event)
            if app.CheckCal
                File = {app.Pins.Pot(:)};
                uisave('File','Settings/Cal/Calibration.mat')
            else
                app.Status('please calibrate pins first');
            end
            figure(app.OWIUIFigure);
        end

        % Button pushed function: PinSaveButton
        function SavePinsCallback(app, event)
            if app.Flag.PinSet
                File{1} = app.Pins;
                File{2} = app.Pins.Light;
                File{3} = app.Pins.Interrupt;
                uisave('File','Settings/Pins/Pins.mat')
            else
                app.Status('Please set pins first');
            end
            figure(app.OWIUIFigure);
        end

        % Button pushed function: SaveMotionButton
        function SaveMotionCallback(app, event)
            if numel(app.Program)
                File = app.Program;
                uisave('File','Settings/Move/Movement')
            else
                app.Status('Start a motion first');
            end
            figure(app.OWIUIFigure);
        end

        % Value changed function: UndockButton
        function UndockButtonPushed(app, event)
            if app.UndockButton.Value
                app.ControlsDock = uifigure('Visible','off','Name','Controls',...
                    'CloseRequestFcn',@app.CloseDock, 'Position',[200 50 300 500]);
                app.UndockButton.Text = 'Dock';
                app.DockPanel.Parent = app.ControlsDock;
                app.DockPanel.Position = [0 0 300 500];
                app.UIAxes.Layout.Row = [1 9];
                app.UIAxes.Layout.Column = [1 7];
                app.ControlsDock.Visible = true;
            else
                app.UIAxes.Layout.Column = [1 5];
                app.UIAxes.Layout.Row = [1 7];
                app.DockPanel.Parent = app.Grid_Main;
                app.DockPanel.Layout.Row = [1 10];
                app.DockPanel.Layout.Column = [6 7];
                app.UndockButton.Text = 'Undock';
                delete(app.ControlsDock);
            end
        end

        % Close request function: OWIUIFigure
        function OWIUIFigureCloseRequest(app, event)
            delete(app.ControlsDock);
            delete(app)
        end

        % Button pushed function: SaveconfigbeforeselectedButton
        function SaveconfigbeforeselectedButtonPushed(app, event)
            if ~isempty(app.Program)
                new.Config = app.GetPlotConfig;
                index = app.ConfigListBox.Value;
                if index == 1
                    app.Program = [new, app.Program];
                else
                    old = app.Program(index:size(app.Program,2));
                    app.Program = [app.Program(1:index-1), new, old];
                end
                app.Status([app.Program(index).Config{:}]);
                app.Status(strcat('Config ',num2str(app.SaveIndex),' saved'));
                app.ConfigListBox.Items{app.SaveIndex} = strcat('Config ',num2str(app.SaveIndex));
                app.ConfigListBox.ItemsData{app.SaveIndex} = app.SaveIndex;
                app.ConfigListBox.Value = index;
                app.SaveIndex = app.SaveIndex + 1;
            else
                app.SaveconfigafterselectedButtonPushed;
            end
        end

        % Button pushed function: SaveconfigafterselectedButton
        function SaveconfigafterselectedButtonPushed(app, event)
            new.Config = app.GetPlotConfig;
            app.ConfigListBox.Items{app.SaveIndex} = strcat('Config ',num2str(app.SaveIndex));
            app.ConfigListBox.ItemsData{app.SaveIndex} = app.SaveIndex;
            if isempty(app.Program) || app.ConfigListBox.Value == app.SaveIndex-1
                index = app.SaveIndex;
                app.Program(index).Config = app.GetPlotConfig;
                app.ConfigListBox.Value = index;
            else
                index = app.ConfigListBox.Value;
                old = app.Program(index+1:size(app.Program,2));
                app.Program = [app.Program(1:index),new,old];
                app.ConfigListBox.Value = index+1;
            end
            app.Status([app.Program(app.SaveIndex).Config{:}]);
            app.Status(strcat('Config ',num2str(app.SaveIndex),' saved'));
            app.SaveIndex = app.SaveIndex + 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OWIUIFigure = uifigure('Visible', 'off');
            app.OWIUIFigure.Name = 'OWI';
            app.OWIUIFigure.CloseRequestFcn = createCallbackFcn(app, @OWIUIFigureCloseRequest, true);
            app.OWIUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @OWIUIFigureWindowKeyPress, true);
            app.ArduinoPortMenu = uimenu(app.OWIUIFigure);
            app.ArduinoPortMenu.MenuSelectedFcn = createCallbackFcn(app, @ArduinoPortMenuSelected, true);
            app.ArduinoPortMenu.Tooltip = {'Select arduino port'};
            app.ArduinoPortMenu.Text = 'Arduino Port';
            app.Grid_Main = uigridlayout(app.OWIUIFigure);
            app.UpdatePlotButton = uibutton(app.Grid_Main, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotCallback, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.LightButton = uibutton(app.Grid_Main, 'state');
            app.LightButton.ValueChangedFcn = createCallbackFcn(app, @RemoteCallback, true);
            app.LightButton.Visible = 'off';
            app.LightButton.Text = 'Light';
            app.UIAxes = uiaxes(app.Grid_Main);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.DockPanel = uipanel(app.Grid_Main);
            app.GridLayout = uigridlayout(app.DockPanel);
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Editable = 'off';
            app.UndockButton = uibutton(app.GridLayout, 'state');
            app.UndockButton.ValueChangedFcn = createCallbackFcn(app, @UndockButtonPushed, true);
            app.UndockButton.Text = 'Undock';
            app.Stop = uibutton(app.GridLayout, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopCallback, true);
            app.Stop.Text = {'STOP'; ''};
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DisplayTab = uitab(app.TabGroup);
            app.DisplayTab.Title = 'Display';
            app.Grid_Display = uigridlayout(app.DisplayTab);
            app.SlideSettings = uipanel(app.Grid_Display);
            app.SlideSettings.Title = 'SLIDE LIMITS';
            app.Grid_SlideSettings = uigridlayout(app.SlideSettings);
            app.MotorDropDown = uidropdown(app.Grid_SlideSettings);
            app.MotorDropDown.Items = {'M1', 'M2', 'M3', 'M4', 'M5'};
            app.MotorDropDown.ItemsData = {'5', '4', '3', '2', '1'};
            app.MotorDropDown.ValueChangedFcn = createCallbackFcn(app, @MotorDropDownValueChanged, true);
            app.MotorDropDown.Value = '5';
            app.ResetcurrentslideButton = uibutton(app.Grid_SlideSettings, 'push');
            app.ResetcurrentslideButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSlideLimitsCallback, true);
            app.ResetcurrentslideButton.Text = 'Reset current slide';
            app.ResetallslidesButton = uibutton(app.Grid_SlideSettings, 'push');
            app.ResetallslidesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSlideLimitsCallback, true);
            app.ResetallslidesButton.Text = 'Reset all slides';
            app.MinEditField_2Label = uilabel(app.Grid_SlideSettings);
            app.MinEditField_2Label.Text = {'Min'; ''};
            app.SlideMin = uieditfield(app.Grid_SlideSettings, 'numeric');
            app.MaxEditField_2Label = uilabel(app.Grid_SlideSettings);
            app.MaxEditField_2Label.Text = {'Max'; ''};
            app.SlideMax = uieditfield(app.Grid_SlideSettings, 'numeric');
            app.LimitButton = uibutton(app.Grid_SlideSettings, 'push');
            app.LimitButton.ButtonPushedFcn = createCallbackFcn(app, @LimitSlideCallback, true);
            app.LimitButton.Text = {'Limit'; ''};
            app.AxisSettings = uipanel(app.Grid_Display);
            app.AxisSettings.Title = 'AXIS LIMITS';
            app.AxisSettings.Visible = 'off';
            app.Grid_AxisSettings = uigridlayout(app.AxisSettings);
            app.Minx = uieditfield(app.Grid_AxisSettings, 'numeric');
            app.Maxx = uieditfield(app.Grid_AxisSettings, 'numeric');
            app.Miny = uieditfield(app.Grid_AxisSettings, 'numeric');
            app.Minz = uieditfield(app.Grid_AxisSettings, 'numeric');
            app.Maxy = uieditfield(app.Grid_AxisSettings, 'numeric');
            app.Maxz = uieditfield(app.Grid_AxisSettings, 'numeric');
            app.xLabel = uilabel(app.Grid_AxisSettings);
            app.xLabel.Text = 'x';
            app.yLabel = uilabel(app.Grid_AxisSettings);
            app.yLabel.Text = 'y';
            app.zLabel = uilabel(app.Grid_AxisSettings);
            app.zLabel.Text = 'z';
            app.AxisSet = uibutton(app.Grid_AxisSettings, 'push');
            app.AxisSet.ButtonPushedFcn = createCallbackFcn(app, @ResetAxisCallback, true);
            app.AxisSet.Text = 'Set';
            app.AxisReset = uibutton(app.Grid_AxisSettings, 'push');
            app.AxisReset.ButtonPushedFcn = createCallbackFcn(app, @ResetAxisCallback, true);
            app.AxisReset.Text = 'Reset';
            app.MinLabel = uilabel(app.Grid_AxisSettings);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.Grid_AxisSettings);
            app.MaxLabel.Text = 'Max';
            app.DHTable = uitable(app.Grid_Display);
            app.DHTable.ColumnName = {'r'; 'alpha'; 'd'; 'theta'};
            app.DHTable.RowName = {'M2'; 'M3'; 'M4'; 'M5'};
            app.DHTable.ColumnSortable = true;
            app.DHTable.ColumnEditable = false;
            app.DHTable.Tooltip = {'Robot''s DH Parameters'};
            app.RemoteSettings = uipanel(app.Grid_Display);
            app.RemoteSettings.Tooltip = {'Remote Control'};
            app.RemoteSettings.Title = 'Remote';
            app.Grid_Remote = uigridlayout(app.RemoteSettings);
            app.Remote5 = uibutton(app.Grid_Remote, 'state');
            app.Remote5.ValueChangedFcn = createCallbackFcn(app, @RemoteCallback, true);
            app.Remote5.Enable = 'off';
            app.Remote5.Text = 'M1';
            app.Remote4 = uibutton(app.Grid_Remote, 'state');
            app.Remote4.ValueChangedFcn = createCallbackFcn(app, @RemoteCallback, true);
            app.Remote4.Enable = 'off';
            app.Remote4.Text = 'M2';
            app.Remote3 = uibutton(app.Grid_Remote, 'state');
            app.Remote3.ValueChangedFcn = createCallbackFcn(app, @RemoteCallback, true);
            app.Remote3.Enable = 'off';
            app.Remote3.Text = 'M3';
            app.Remote2 = uibutton(app.Grid_Remote, 'state');
            app.Remote2.ValueChangedFcn = createCallbackFcn(app, @RemoteCallback, true);
            app.Remote2.Enable = 'off';
            app.Remote2.Text = 'M4';
            app.Remote1 = uibutton(app.Grid_Remote, 'state');
            app.Remote1.ValueChangedFcn = createCallbackFcn(app, @RemoteCallback, true);
            app.Remote1.Enable = 'off';
            app.Remote1.Text = 'M5';
            app.ViewSettings = uipanel(app.Grid_Display);
            app.ViewSettings.Title = 'VIEWS';
            app.Grid_ViewSettings = uigridlayout(app.ViewSettings);
            app.ViewYX = uibutton(app.Grid_ViewSettings, 'push');
            app.ViewYX.ButtonPushedFcn = createCallbackFcn(app, @ViewCallback, true);
            app.ViewYX.Text = 'YX';
            app.ViewXZ = uibutton(app.Grid_ViewSettings, 'push');
            app.ViewXZ.ButtonPushedFcn = createCallbackFcn(app, @ViewCallback, true);
            app.ViewXZ.Text = 'XZ';
            app.ViewYZ = uibutton(app.Grid_ViewSettings, 'push');
            app.ViewYZ.ButtonPushedFcn = createCallbackFcn(app, @ViewCallback, true);
            app.ViewYZ.Text = 'YZ';
            app.ViewReset = uibutton(app.Grid_ViewSettings, 'push');
            app.ViewReset.ButtonPushedFcn = createCallbackFcn(app, @ViewCallback, true);
            app.ViewReset.Text = 'Reset';
            app.ViewRotateButton = uibutton(app.Grid_ViewSettings, 'state');
            app.ViewRotateButton.ValueChangedFcn = createCallbackFcn(app, @ViewCallback, true);
            app.ViewRotateButton.Text = 'Rotate';
            app.ViewRotateButton.Value = true;
            app.ViewIso = uibutton(app.Grid_ViewSettings, 'push');
            app.ViewIso.ButtonPushedFcn = createCallbackFcn(app, @ViewCallback, true);
            app.ViewIso.Text = 'Isometric';
            app.View_YZ = uibutton(app.Grid_ViewSettings, 'push');
            app.View_YZ.ButtonPushedFcn = createCallbackFcn(app, @ViewCallback, true);
            app.View_YZ.Text = 'YZ''';
            app.View_XZ = uibutton(app.Grid_ViewSettings, 'push');
            app.View_XZ.ButtonPushedFcn = createCallbackFcn(app, @ViewCallback, true);
            app.View_XZ.Text = 'XZ''';
            app.PowerPanel = uipanel(app.Grid_Display);
            app.Grid_Power = uigridlayout(app.PowerPanel);
            app.Power1 = uispinner(app.Grid_Power);
            app.Power1.Step = 0.1;
            app.Power1.Limits = [0 2];
            app.Power1.Value = 1;
            app.Power2 = uispinner(app.Grid_Power);
            app.Power2.Step = 0.1;
            app.Power2.Limits = [0 2];
            app.Power2.Value = 1;
            app.Power3 = uispinner(app.Grid_Power);
            app.Power3.Step = 0.1;
            app.Power3.Limits = [0 2];
            app.Power3.Value = 1;
            app.Power4 = uispinner(app.Grid_Power);
            app.Power4.Step = 0.1;
            app.Power4.Limits = [0 2];
            app.Power4.Value = 1;
            app.Power5 = uispinner(app.Grid_Power);
            app.Power5.Step = 0.1;
            app.Power5.Limits = [0 2];
            app.Power5.Value = 1;
            app.UploadAllButton = uibutton(app.Grid_Display, 'push');
            app.UploadAllButton.ButtonPushedFcn = createCallbackFcn(app, @UploadCallback, true);
            app.UploadAllButton.Text = {'Upload '; 'All'};
            app.ResetArmButton = uibutton(app.Grid_Display, 'push');
            app.ResetArmButton.ButtonPushedFcn = createCallbackFcn(app, @ResetArmCallback, true);
            app.ResetArmButton.Text = {'Reset '; 'Arm'};
            app.RadiansButton = uibutton(app.Grid_Display, 'state');
            app.RadiansButton.ValueChangedFcn = createCallbackFcn(app, @DegreeChangeCallback, true);
            app.RadiansButton.Text = 'Degrees';
            app.ControlPanel = uipanel(app.Grid_Display);
            app.Grid_Control = uigridlayout(app.ControlPanel);
            app.SlideReset1 = uibutton(app.Grid_Control, 'push');
            app.SlideReset1.ButtonPushedFcn = createCallbackFcn(app, @ResetArmCallback, true);
            app.SlideReset1.Text = 'M5';
            app.SlideText1 = uieditfield(app.Grid_Control, 'numeric');
            app.SlideText1.ValueDisplayFormat = '%.1f';
            app.SlideText1.ValueChangedFcn = createCallbackFcn(app, @SlideTextValueChanged, true);
            app.Slide1 = uislider(app.Grid_Control);
            app.Slide1.MajorTicks = [];
            app.Slide1.ValueChangedFcn = createCallbackFcn(app, @SliderChangedCallback, true);
            app.Slide1.ValueChangingFcn = createCallbackFcn(app, @SliderChangingCallback, true);
            app.Slide1.MinorTicks = [];
            app.Upload1 = uibutton(app.Grid_Control, 'push');
            app.Upload1.ButtonPushedFcn = createCallbackFcn(app, @UploadCallback, true);
            app.Upload1.Tooltip = {'Upload position to arm'};
            app.Upload1.Text = '^';
            app.SlideReset2 = uibutton(app.Grid_Control, 'push');
            app.SlideReset2.ButtonPushedFcn = createCallbackFcn(app, @ResetArmCallback, true);
            app.SlideReset2.Text = 'M4';
            app.SlideText2 = uieditfield(app.Grid_Control, 'numeric');
            app.SlideText2.ValueDisplayFormat = '%.1f';
            app.SlideText2.ValueChangedFcn = createCallbackFcn(app, @SlideTextValueChanged, true);
            app.Slide2 = uislider(app.Grid_Control);
            app.Slide2.MajorTicks = [];
            app.Slide2.ValueChangedFcn = createCallbackFcn(app, @SliderChangedCallback, true);
            app.Slide2.ValueChangingFcn = createCallbackFcn(app, @SliderChangingCallback, true);
            app.Slide2.MinorTicks = [];
            app.Upload2 = uibutton(app.Grid_Control, 'push');
            app.Upload2.ButtonPushedFcn = createCallbackFcn(app, @UploadCallback, true);
            app.Upload2.Tooltip = {'Upload position to arm'};
            app.Upload2.Text = '^';
            app.SlideReset3 = uibutton(app.Grid_Control, 'push');
            app.SlideReset3.ButtonPushedFcn = createCallbackFcn(app, @ResetArmCallback, true);
            app.SlideReset3.Text = 'M3';
            app.SlideText3 = uieditfield(app.Grid_Control, 'numeric');
            app.SlideText3.ValueDisplayFormat = '%.1f';
            app.SlideText3.ValueChangedFcn = createCallbackFcn(app, @SlideTextValueChanged, true);
            app.Slide3 = uislider(app.Grid_Control);
            app.Slide3.MajorTicks = [];
            app.Slide3.ValueChangedFcn = createCallbackFcn(app, @SliderChangedCallback, true);
            app.Slide3.ValueChangingFcn = createCallbackFcn(app, @SliderChangingCallback, true);
            app.Slide3.MinorTicks = [];
            app.Upload3 = uibutton(app.Grid_Control, 'push');
            app.Upload3.ButtonPushedFcn = createCallbackFcn(app, @UploadCallback, true);
            app.Upload3.Tooltip = {'Upload position to arm'};
            app.Upload3.Text = '^';
            app.SlideReset4 = uibutton(app.Grid_Control, 'push');
            app.SlideReset4.ButtonPushedFcn = createCallbackFcn(app, @ResetArmCallback, true);
            app.SlideReset4.Text = 'M2';
            app.SlideText4 = uieditfield(app.Grid_Control, 'numeric');
            app.SlideText4.ValueDisplayFormat = '%5.1f';
            app.SlideText4.ValueChangedFcn = createCallbackFcn(app, @SlideTextValueChanged, true);
            app.Slide4 = uislider(app.Grid_Control);
            app.Slide4.MajorTicks = [];
            app.Slide4.ValueChangedFcn = createCallbackFcn(app, @SliderChangedCallback, true);
            app.Slide4.ValueChangingFcn = createCallbackFcn(app, @SliderChangingCallback, true);
            app.Slide4.MinorTicks = [];
            app.Upload4 = uibutton(app.Grid_Control, 'push');
            app.Upload4.ButtonPushedFcn = createCallbackFcn(app, @UploadCallback, true);
            app.Upload4.Tooltip = {'Upload position to arm'};
            app.Upload4.Text = '^';
            app.SlideReset5 = uibutton(app.Grid_Control, 'push');
            app.SlideReset5.ButtonPushedFcn = createCallbackFcn(app, @ResetArmCallback, true);
            app.SlideReset5.Text = 'M1';
            app.SlideText5 = uieditfield(app.Grid_Control, 'numeric');
            app.SlideText5.RoundFractionalValues = 'on';
            app.SlideText5.ValueDisplayFormat = '%.1f';
            app.SlideText5.ValueChangedFcn = createCallbackFcn(app, @SlideTextValueChanged, true);
            app.Slide5 = uislider(app.Grid_Control);
            app.Slide5.Limits = [0 45];
            app.Slide5.MajorTicks = [];
            app.Slide5.ValueChangedFcn = createCallbackFcn(app, @SliderChangedCallback, true);
            app.Slide5.ValueChangingFcn = createCallbackFcn(app, @SliderChangingCallback, true);
            app.Slide5.MinorTicks = [];
            app.Upload5 = uibutton(app.Grid_Control, 'push');
            app.Upload5.ButtonPushedFcn = createCallbackFcn(app, @UploadCallback, true);
            app.Upload5.Tooltip = {'Upload position to arm'};
            app.Upload5.Text = '^';
            app.DisplaySettingsButtonGroup = uibuttongroup(app.Grid_Display);
            app.DisplaySettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplaySettingsButtonGroupSelectionChanged, true);
            app.AxisLimitsButton = uitogglebutton(app.DisplaySettingsButtonGroup);
            app.AxisLimitsButton.Text = {'Axis'; 'Limits'};
            app.SlideLimitsButton = uitogglebutton(app.DisplaySettingsButtonGroup);
            app.SlideLimitsButton.Text = {'Slide'; 'Limits'};
            app.DHButton = uitogglebutton(app.DisplaySettingsButtonGroup);
            app.DHButton.Text = {'DH'; ''};
            app.DHButton.Value = true;
            app.ArduinoSetupTab = uitab(app.TabGroup);
            app.ArduinoSetupTab.Title = 'Arduino Setup';
            app.Grid_Arduino = uigridlayout(app.ArduinoSetupTab);
            app.CalPanel = uipanel(app.Grid_Arduino);
            app.CalPanel.Title = 'Calibration';
            app.CalPanel.Visible = 'off';
            app.Grid_Calibrate = uigridlayout(app.CalPanel);
            app.OrientButton = uibutton(app.Grid_Calibrate, 'push');
            app.OrientButton.ButtonPushedFcn = createCallbackFcn(app, @OrientationCallback, true);
            app.OrientButton.Text = 'Orient';
            app.ReadPotButton = uibutton(app.Grid_Calibrate, 'push');
            app.ReadPotButton.ButtonPushedFcn = createCallbackFcn(app, @ReadPotCallback, true);
            app.ReadPotButton.Text = 'Read Pot';
            app.PotReadingEditFieldLabel = uilabel(app.Grid_Calibrate);
            app.PotReadingEditFieldLabel.Text = 'Pot Reading';
            app.PotReadingEditField = uieditfield(app.Grid_Calibrate, 'numeric');
            app.PotReadingEditField.ValueDisplayFormat = '%.2f';
            app.PotReadingEditField.Editable = 'off';
            app.CalLoadButton = uibutton(app.Grid_Calibrate, 'push');
            app.CalLoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCalibrationCallback, true);
            app.CalLoadButton.Text = 'Load';
            app.CalSaveButton = uibutton(app.Grid_Calibrate, 'push');
            app.CalSaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCalibrationCallback, true);
            app.CalSaveButton.Text = 'Save';
            app.SetCalButton = uibutton(app.Grid_Calibrate, 'push');
            app.SetCalButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateCallback, true);
            app.SetCalButton.Text = 'Set';
            app.CalGroup = uibuttongroup(app.Grid_Calibrate);
            app.CalGroup.SelectionChangedFcn = createCallbackFcn(app, @CalButtonCallback, true);
            app.M1CalButton = uitogglebutton(app.CalGroup);
            app.M1CalButton.Text = 'M1';
            app.M1CalButton.Value = true;
            app.M2CalButton = uitogglebutton(app.CalGroup);
            app.M2CalButton.Text = 'M2';
            app.M3CalButton = uitogglebutton(app.CalGroup);
            app.M3CalButton.Text = 'M3';
            app.M4CalButton = uitogglebutton(app.CalGroup);
            app.M4CalButton.Text = 'M4';
            app.M5CalButton = uitogglebutton(app.CalGroup);
            app.M5CalButton.Text = 'M5';
            app.PinSettingsPanel = uipanel(app.Grid_Arduino);
            app.PinSettingsPanel.Title = 'Pin Settings';
            app.Grid_Pins = uigridlayout(app.PinSettingsPanel);
            app.InterruptLabel = uilabel(app.Grid_Pins);
            app.InterruptLabel.Text = 'Interrupt';
            app.Interrupt = uieditfield(app.Grid_Pins, 'numeric');
            app.Interrupt.Limits = [0 15];
            app.Interrupt.RoundFractionalValues = 'on';
            app.Interrupt.ValueDisplayFormat = '%.0f';
            app.Interrupt.Value = 5;
            app.PinSetButton = uibutton(app.Grid_Pins, 'push');
            app.PinSetButton.ButtonPushedFcn = createCallbackFcn(app, @SetPinCallback, true);
            app.PinSetButton.Text = 'Apply Values';
            app.PinSaveButton = uibutton(app.Grid_Pins, 'push');
            app.PinSaveButton.ButtonPushedFcn = createCallbackFcn(app, @SavePinsCallback, true);
            app.PinSaveButton.Text = 'Save';
            app.PinLoadButton = uibutton(app.Grid_Pins, 'push');
            app.PinLoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPinsCallback, true);
            app.PinLoadButton.Text = 'Load';
            app.DirLabel = uilabel(app.Grid_Pins);
            app.DirLabel.Tooltip = {'These values indicate the direction the motors move'};
            app.DirLabel.Text = 'Dir';
            app.EnLabel = uilabel(app.Grid_Pins);
            app.EnLabel.Tooltip = {'These values indicate what pins enable the motors'};
            app.EnLabel.Text = 'En';
            app.LightLabel = uilabel(app.Grid_Pins);
            app.LightLabel.Text = 'Light';
            app.Light = uieditfield(app.Grid_Pins, 'numeric');
            app.Light.Limits = [0 53];
            app.Light.RoundFractionalValues = 'on';
            app.Light.ValueDisplayFormat = '%.0f';
            app.Light.Value = 13;
            app.M5PotPinEditFieldLabel = uilabel(app.Grid_Pins);
            app.M5PotPinEditFieldLabel.Text = 'M5 Pot Pin';
            app.APin1 = uieditfield(app.Grid_Pins, 'numeric');
            app.APin1.Limits = [0 15];
            app.APin1.RoundFractionalValues = 'on';
            app.APin1.ValueDisplayFormat = '%.0f';
            app.APin1.Value = 4;
            app.M5OutputPinsEditFieldLabel = uilabel(app.Grid_Pins);
            app.M5OutputPinsEditFieldLabel.Text = 'M5 Output Pins';
            app.En1 = uieditfield(app.Grid_Pins, 'numeric');
            app.En1.Limits = [0 53];
            app.En1.RoundFractionalValues = 'on';
            app.En1.ValueDisplayFormat = '%.0f';
            app.En1.Value = 7;
            app.M4PotPinLabel = uilabel(app.Grid_Pins);
            app.M4PotPinLabel.Text = 'M4 Pot Pin';
            app.APin2 = uieditfield(app.Grid_Pins, 'numeric');
            app.APin2.Limits = [0 15];
            app.APin2.RoundFractionalValues = 'on';
            app.APin2.ValueDisplayFormat = '%.0f';
            app.APin2.Value = 3;
            app.M4OutputPinsLabel = uilabel(app.Grid_Pins);
            app.M4OutputPinsLabel.Text = 'M4 Output Pins';
            app.En2 = uieditfield(app.Grid_Pins, 'numeric');
            app.En2.Limits = [0 53];
            app.En2.RoundFractionalValues = 'on';
            app.En2.ValueDisplayFormat = '%.0f';
            app.En2.Value = 6;
            app.M3PotPinLabel = uilabel(app.Grid_Pins);
            app.M3PotPinLabel.Text = 'M3 Pot Pin';
            app.APin3 = uieditfield(app.Grid_Pins, 'numeric');
            app.APin3.Limits = [0 15];
            app.APin3.RoundFractionalValues = 'on';
            app.APin3.ValueDisplayFormat = '%.0f';
            app.APin3.Value = 2;
            app.M3OutputPinsLabel = uilabel(app.Grid_Pins);
            app.M3OutputPinsLabel.Text = 'M3 Output Pins';
            app.En3 = uieditfield(app.Grid_Pins, 'numeric');
            app.En3.Limits = [0 53];
            app.En3.RoundFractionalValues = 'on';
            app.En3.ValueDisplayFormat = '%.0f';
            app.En3.Value = 5;
            app.M2PotPinLabel = uilabel(app.Grid_Pins);
            app.M2PotPinLabel.Text = 'M2 Pot Pin';
            app.APin4 = uieditfield(app.Grid_Pins, 'numeric');
            app.APin4.Limits = [0 15];
            app.APin4.RoundFractionalValues = 'on';
            app.APin4.ValueDisplayFormat = '%.0f';
            app.APin4.Value = 1;
            app.M2OutputPinsLabel = uilabel(app.Grid_Pins);
            app.M2OutputPinsLabel.Text = 'M2 Output Pins';
            app.En4 = uieditfield(app.Grid_Pins, 'numeric');
            app.En4.Limits = [0 53];
            app.En4.RoundFractionalValues = 'on';
            app.En4.ValueDisplayFormat = '%.0f';
            app.En4.Value = 4;
            app.M1PotPinLabel = uilabel(app.Grid_Pins);
            app.M1PotPinLabel.Text = 'M1 Pot Pin';
            app.APin5 = uieditfield(app.Grid_Pins, 'numeric');
            app.APin5.Limits = [0 15];
            app.APin5.RoundFractionalValues = 'on';
            app.APin5.ValueDisplayFormat = '%.0f';
            app.M1OutputPinsLabel = uilabel(app.Grid_Pins);
            app.M1OutputPinsLabel.Text = 'M1 Output Pins';
            app.En5 = uieditfield(app.Grid_Pins, 'numeric');
            app.En5.Limits = [0 53];
            app.En5.RoundFractionalValues = 'on';
            app.En5.ValueDisplayFormat = '%.0f';
            app.En5.Value = 3;
            app.Dir1 = uieditfield(app.Grid_Pins, 'numeric');
            app.Dir1.Limits = [0 53];
            app.Dir1.RoundFractionalValues = 'on';
            app.Dir1.ValueDisplayFormat = '%.0f';
            app.Dir1.Value = 12;
            app.Dir2 = uieditfield(app.Grid_Pins, 'numeric');
            app.Dir2.Limits = [0 53];
            app.Dir2.RoundFractionalValues = 'on';
            app.Dir2.ValueDisplayFormat = '%.0f';
            app.Dir2.Value = 11;
            app.Dir3 = uieditfield(app.Grid_Pins, 'numeric');
            app.Dir3.Limits = [0 53];
            app.Dir3.RoundFractionalValues = 'on';
            app.Dir3.ValueDisplayFormat = '%.0f';
            app.Dir3.Value = 10;
            app.Dir4 = uieditfield(app.Grid_Pins, 'numeric');
            app.Dir4.Limits = [0 53];
            app.Dir4.RoundFractionalValues = 'on';
            app.Dir4.ValueDisplayFormat = '%.0f';
            app.Dir4.Value = 9;
            app.Dir5 = uieditfield(app.Grid_Pins, 'numeric');
            app.Dir5.Limits = [0 53];
            app.Dir5.RoundFractionalValues = 'on';
            app.Dir5.ValueDisplayFormat = '%.0f';
            app.Dir5.Value = 8;
            app.AutomationTab = uitab(app.TabGroup);
            app.AutomationTab.Title = 'Automation';
            app.Grid_Automation = uigridlayout(app.AutomationTab);
            app.ClosedLoopPanel = uipanel(app.Grid_Automation);
            app.ClosedLoopPanel.Title = 'Closed Loop';
            app.Grid_Program = uigridlayout(app.ClosedLoopPanel);
            app.ConfigurationsListBoxLabel = uilabel(app.Grid_Program);
            app.ConfigurationsListBoxLabel.Text = 'Configurations';
            app.ConfigListBox = uilistbox(app.Grid_Program);
            app.ConfigListBox.Items = {};
            app.ConfigListBox.ValueChangedFcn = createCallbackFcn(app, @ListConfigCallback, true);
            app.ConfigListBox.Value = {};
            app.UploadMotionButton = uibutton(app.Grid_Program, 'push');
            app.UploadMotionButton.ButtonPushedFcn = createCallbackFcn(app, @UploadMotionCallback, true);
            app.UploadMotionButton.Text = 'Upload Motion';
            app.DisplayMotionButton = uibutton(app.Grid_Program, 'state');
            app.DisplayMotionButton.ValueChangedFcn = createCallbackFcn(app, @DisplayMotionCallback, true);
            app.DisplayMotionButton.Text = {'Show'; 'Motion'};
            app.DeleteConfigButton = uibutton(app.Grid_Program, 'push');
            app.DeleteConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigDeleteCallback, true);
            app.DeleteConfigButton.Text = {'Delete'; 'Selected '};
            app.SaveMotionButton = uibutton(app.Grid_Program, 'push');
            app.SaveMotionButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMotionCallback, true);
            app.SaveMotionButton.Text = {'Save'; 'Motion'};
            app.LoadMotionButton = uibutton(app.Grid_Program, 'push');
            app.LoadMotionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMotionCallback, true);
            app.LoadMotionButton.Text = {'Load'; 'Motion'};
            app.ResetMotionButton = uibutton(app.Grid_Program, 'push');
            app.ResetMotionButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigResetCallback, true);
            app.ResetMotionButton.Text = {'Reset'; 'Motion'};
            app.SlowMotionButton = uibutton(app.Grid_Program, 'state');
            app.SlowMotionButton.Text = 'Slow Motion';
            app.SaveconfigafterselectedButton = uibutton(app.Grid_Program, 'push');
            app.SaveconfigafterselectedButton.ButtonPushedFcn = createCallbackFcn(app, @SaveconfigafterselectedButtonPushed, true);
            app.SaveconfigafterselectedButton.Text = {'Save config'; 'after selected'};
            app.SaveconfigbeforeselectedButton = uibutton(app.Grid_Program, 'push');
            app.SaveconfigbeforeselectedButton.ButtonPushedFcn = createCallbackFcn(app, @SaveconfigbeforeselectedButtonPushed, true);
            app.SaveconfigbeforeselectedButton.Text = {'Save config'; 'before selected'};
            % Show the figure after all components are created
            app.OWIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OWI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OWIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OWIUIFigure)
        end
    end
end